classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NetIncomeEditFieldLabel      matlab.ui.control.Label
        NetIncomeEditField           matlab.ui.control.NumericEditField
        TotalAssetsEditFieldLabel    matlab.ui.control.Label
        TotalAssetsEditField         matlab.ui.control.NumericEditField
        NetLoanLossesEditFieldLabel  matlab.ui.control.Label
        NetLoanLossesEditField       matlab.ui.control.NumericEditField
        NonPerformingLoansEditFieldLabel  matlab.ui.control.Label
        NonPerformingLoansEditField  matlab.ui.control.NumericEditField
        TotalLoansEditFieldLabel     matlab.ui.control.Label
        TotalLoansEditField          matlab.ui.control.NumericEditField
        ProvisionforLoanLossesEditFieldLabel  matlab.ui.control.Label
        ProvisionforLoanLossesEditField  matlab.ui.control.NumericEditField
        ExecuteButton                matlab.ui.control.Button
        NLLAAEditFieldLabel          matlab.ui.control.Label
        NLLAAEditField               matlab.ui.control.EditField
        NITAEditFieldLabel           matlab.ui.control.Label
        NITAEditField                matlab.ui.control.EditField
        NPLTAEditFieldLabel          matlab.ui.control.Label
        NPLTAEditField               matlab.ui.control.EditField
        NLLTLEditFieldLabel          matlab.ui.control.Label
        NLLTLEditField               matlab.ui.control.EditField
        NLLPLLNIEditFieldLabel       matlab.ui.control.Label
        NLLPLLNIEditField            matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            nita = app.NetIncomeEditField.Value/(app.TotalAssetsEditField.Value + realmin);
            app.NITAEditField.Value = sprintf("%.2f",nita);
            nllaa = app.NetLoanLossesEditField.Value/(app.TotalAssetsEditField.Value - app.TotalLoansEditField.Value + realmin);
            app.NLLAAEditField.Value = sprintf("%.2f",nllaa);
            nplta = app.NonPerformingLoansEditField.Value/(app.TotalAssetsEditField.Value + realmin);
            app.NPLTAEditField.Value = sprintf("%.2f",nplta);
            nlltl = app.NetLoanLossesEditField.Value/(app.TotalAssetsEditField.Value + realmin);
            app.NLLTLEditField.Value = sprintf("%.2f",nlltl);
            nllpllni = (app.NetLoanLossesEditField.Value + app.ProvisionforLoanLossesEditField.Value)/(app.NetIncomeEditField.Value + realmin);
            app.NLLPLLNIEditField.Value = sprintf("%.2f",nllpllni);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NetIncomeEditFieldLabel = uilabel(app.UIFigure);
            app.NetIncomeEditFieldLabel.Text = 'Net Income';
            app.NetIncomeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalAssetsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalAssetsEditFieldLabel.Text = 'Total Assets';
            app.TotalAssetsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NetLoanLossesEditFieldLabel = uilabel(app.UIFigure);
            app.NetLoanLossesEditFieldLabel.Text = 'Net Loan Losses';
            app.NetLoanLossesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NonPerformingLoansEditFieldLabel = uilabel(app.UIFigure);
            app.NonPerformingLoansEditFieldLabel.Text = 'Non-Performing Loans';
            app.NonPerformingLoansEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalLoansEditFieldLabel = uilabel(app.UIFigure);
            app.TotalLoansEditFieldLabel.Text = 'Total Loans';
            app.TotalLoansEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProvisionforLoanLossesEditFieldLabel = uilabel(app.UIFigure);
            app.ProvisionforLoanLossesEditFieldLabel.Text = 'Provision for Loan Losses';
            app.ProvisionforLoanLossesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.NLLAAEditFieldLabel = uilabel(app.UIFigure);
            app.NLLAAEditFieldLabel.Text = 'NLLAA';
            app.NLLAAEditField = uieditfield(app.UIFigure, 'text');
            app.NITAEditFieldLabel = uilabel(app.UIFigure);
            app.NITAEditFieldLabel.Text = 'NITA';
            app.NITAEditField = uieditfield(app.UIFigure, 'text');
            app.NPLTAEditFieldLabel = uilabel(app.UIFigure);
            app.NPLTAEditFieldLabel.Text = 'NPLTA';
            app.NPLTAEditField = uieditfield(app.UIFigure, 'text');
            app.NLLTLEditFieldLabel = uilabel(app.UIFigure);
            app.NLLTLEditFieldLabel.Text = 'NLLTL';
            app.NLLTLEditField = uieditfield(app.UIFigure, 'text');
            app.NLLPLLNIEditFieldLabel = uilabel(app.UIFigure);
            app.NLLPLLNIEditFieldLabel.Text = 'NLLPLLNI';
            app.NLLPLLNIEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end